classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image2                    matlab.ui.control.Image
        KdSlider                  matlab.ui.control.Slider
        Jkgm2Label_4              matlab.ui.control.Label
        DerivativegainLabel       matlab.ui.control.Label
        KiSlider                  matlab.ui.control.Slider
        Jkgm2Label_3              matlab.ui.control.Label
        IntegralgainLabel         matlab.ui.control.Label
        KpSlider                  matlab.ui.control.Slider
        Jkgm2Label_2              matlab.ui.control.Label
        ProportionalgainLabel     matlab.ui.control.Label
        GenerateButton            matlab.ui.control.Button
        L0001882HLabel            matlab.ui.control.Label
        R006727Label              matlab.ui.control.Label
        MotorTorqueConstantLabel  matlab.ui.control.Label
        ElectromotiveForceConstantLabel  matlab.ui.control.Label
        MotorViscousFrictionConstantLabel  matlab.ui.control.Label
        MomentofInertiaLabel      matlab.ui.control.Label
        KtSlider                  matlab.ui.control.Slider
        KtNmAmpLabel              matlab.ui.control.Label
        KeSlider                  matlab.ui.control.Slider
        KeVradsLabel              matlab.ui.control.Label
        bSlider                   matlab.ui.control.Slider
        bNmsLabel                 matlab.ui.control.Label
        JSlider                   matlab.ui.control.Slider
        Jkgm2Label                matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            J = app.JSlider.Value;
            b = app.bSlider.Value;
            Kt = app.KeSlider.Value;
            Ke = app.KtSlider.Value;
            R = 0.06727;
            L = 0.001882;
            sys = tf(Kt,[J*L b*L+J*R b*R+Kt*Ke 0]);
            plot(app.UIAxes,step(sys));

            %controller settings
            kd=app.KdSlider.Value;
            kp=app.KpSlider.Value;
            ki=app.KiSlider.Value;
            pid = tf([Kt*kd Kt*kp Kt*ki],[J*L b*L+J*R+kd*Kt b*R+Kt*Ke+kp*Kt ki*Kt]);
            plot(app.UIAxes_2,step(pid));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'System Response to unit step input')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 45];
            app.UIAxes.YLim = [0 0.1];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'System Response with PID controller to unit step input')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'MotorDiagram.png');
            app.Jkgm2Label = uilabel(app.UIFigure);
            app.Jkgm2Label.Text = 'J   [kg*m^2]';
            app.JSlider = uislider(app.UIFigure);
            app.JSlider.Limits = [1 1.5];
            app.JSlider.Value = 1;
            app.bNmsLabel = uilabel(app.UIFigure);
            app.bNmsLabel.Text = 'b   [N*m*s]';
            app.bSlider = uislider(app.UIFigure);
            app.bSlider.Limits = [1.5 2];
            app.bSlider.Value = 1.5;
            app.KeVradsLabel = uilabel(app.UIFigure);
            app.KeVradsLabel.Text = 'Ke  [V/rad/s]';
            app.KeSlider = uislider(app.UIFigure);
            app.KeSlider.Limits = [1.5 2];
            app.KeSlider.Value = 1.5;
            app.KtNmAmpLabel = uilabel(app.UIFigure);
            app.KtNmAmpLabel.Text = 'Kt  [N/m/Amp]';
            app.KtSlider = uislider(app.UIFigure);
            app.KtSlider.Limits = [1.5 2];
            app.KtSlider.Value = 1.5;
            app.MomentofInertiaLabel = uilabel(app.UIFigure);
            app.MomentofInertiaLabel.Text = ' Moment of Inertia';
            app.MotorViscousFrictionConstantLabel = uilabel(app.UIFigure);
            app.MotorViscousFrictionConstantLabel.Text = ' Motor Viscous Friction Constant';
            app.ElectromotiveForceConstantLabel = uilabel(app.UIFigure);
            app.ElectromotiveForceConstantLabel.Text = ' Electromotive Force Constant ';
            app.MotorTorqueConstantLabel = uilabel(app.UIFigure);
            app.MotorTorqueConstantLabel.Text = ' Motor Torque Constant';
            app.R006727Label = uilabel(app.UIFigure);
            app.R006727Label.Text = 'R = 0.06727 Ω';
            app.L0001882HLabel = uilabel(app.UIFigure);
            app.L0001882HLabel.Text = 'L = 0.001882 H';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ProportionalgainLabel = uilabel(app.UIFigure);
            app.ProportionalgainLabel.Text = ' Proportional gain';
            app.Jkgm2Label_2 = uilabel(app.UIFigure);
            app.Jkgm2Label_2.Text = 'Kp';
            app.KpSlider = uislider(app.UIFigure);
            app.KpSlider.Limits = [0 10];
            app.KpSlider.Value = 1;
            app.IntegralgainLabel = uilabel(app.UIFigure);
            app.IntegralgainLabel.Text = ' Integral gain';
            app.Jkgm2Label_3 = uilabel(app.UIFigure);
            app.Jkgm2Label_3.Text = 'Ki';
            app.KiSlider = uislider(app.UIFigure);
            app.KiSlider.Limits = [0 10];
            app.KiSlider.Value = 1;
            app.DerivativegainLabel = uilabel(app.UIFigure);
            app.DerivativegainLabel.Text = ' Derivative gain';
            app.Jkgm2Label_4 = uilabel(app.UIFigure);
            app.Jkgm2Label_4.Text = 'Kd';
            app.KdSlider = uislider(app.UIFigure);
            app.KdSlider.Limits = [0 10];
            app.KdSlider.Value = 1;
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'PID.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end